classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageUIFigure                matlab.ui.Figure
        TimeSliderLabel              matlab.ui.control.Label
        TimeSlider                   matlab.ui.control.Slider
        RammEditFieldLabel           matlab.ui.control.Label
        RammEditField                matlab.ui.control.NumericEditField
        UIAxes                       matlab.ui.control.UIAxes
        Load2DimagefromscriptButton  matlab.ui.control.Button
        LoadnewmodelButton           matlab.ui.control.Button
        RbmmEditFieldLabel           matlab.ui.control.Label
        RbmmEditField                matlab.ui.control.NumericEditField
        XommEditFieldLabel           matlab.ui.control.Label
        XommEditField                matlab.ui.control.NumericEditField
        YommEditFieldLabel           matlab.ui.control.Label
        YommEditField                matlab.ui.control.NumericEditField
        ParametersofnewmodelLabel    matlab.ui.control.Label
    end

    properties (Access = public)
        SegImage Image % Image that was selected by user
        SegModel Model 
        t 
    end
    
    methods (Access = private)
        
        function displayImage(app,t,z)
           % app.t = t;
            app.UIAxes.XLim = [0 app.SegImage.dim(2)];
            app.UIAxes.YLim = [0 app.SegImage.dim(1)];
            mat = abs(squeeze(app.SegImage.voxels(t,z,:,:)));
            imagesc(mat,'Parent',app.UIAxes);
            daspect(app.UIAxes,[app.SegImage.voxelSize(1) app.SegImage.voxelSize(2) 1]);
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.SegImage.imageOrigin(2), app.SegImage.imageOrigin(1),'r+', 'MarkerSize', 10);
            hold(app.UIAxes,'off');
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            app.SegImage = Image(1,1,1,1);
        end

        % Button pushed function: Load2DimagefromscriptButton
        function Load2DimagefromscriptButtonPushed(app, event)
            [fileName,filePath] = uigetfile('*.m','Select an image file');
            if isequal(fileName, 0)
                uialert(app.ImageUIFigure, 'You pressed on Cancel. Choose a script!' , 'Image Error');
                return
            else
                uialert(app.ImageUIFigure, 'The script was selected. Wait for the image to load.','Informacja','Icon', 'info');
                app.SegImage = read2DImageFromScript(app.SegImage,filePath,fileName);
                displayImage(app,1,1);
                app.TimeSlider.Limits = [0 app.SegImage.dim(4)];
                app.TimeSlider.MajorTicks = (0:20:app.SegImage.dim(4));
                
            end
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            tc = event.Value;
            app.t = round(tc);
            if app.t<1
                app.t=1;
            end
            displayImage(app,app.t,1);
        end

        % Button pushed function: LoadnewmodelButton
        function LoadnewmodelButtonPushed(app, event)
            %th = 0:pi/50:2*pi;
            displayImage(app,app.t,1);
            th = linspace(0,2*pi);
            if app.XommEditField.Value == 0
               app.XommEditField.Value = app.SegImage.imageOrigin(2);
            end
            if app.YommEditField.Value == 0
               app.YommEditField.Value = app.SegImage.imageOrigin(1);
            end
            if app.RammEditField.Value == 0 || app.RbmmEditField.Value == 0
                 uialert(app.ImageUIFigure, 'R value cannot be 0' , 'Image Error');
                return
            end
            xunit = (app.RammEditField.Value * cos(th) / app.SegImage.voxelSize(2)) + app.XommEditField.Value;
            yunit = (app.RbmmEditField.Value * sin(th) / app.SegImage.voxelSize(1)) + app.YommEditField.Value;
            hold(app.UIAxes);
            plot(app.UIAxes, xunit, yunit);
            % app.SegModel = create2DModelBasedOnEllipse();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageUIFigure = uifigure;
            app.ImageUIFigure.Name = 'Image';
            app.TimeSliderLabel = uilabel(app.ImageUIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.ImageUIFigure);
            app.TimeSlider.MajorTicks = [0 20 40 60 80 100];
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.RammEditFieldLabel = uilabel(app.ImageUIFigure);
            app.RammEditFieldLabel.Text = 'Ra [mm]';
            app.RammEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.RammEditField.Limits = [0 50];
            app.UIAxes = uiaxes(app.ImageUIFigure);
            title(app.UIAxes, 'Image')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[ ]'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.Load2DimagefromscriptButton = uibutton(app.ImageUIFigure, 'push');
            app.Load2DimagefromscriptButton.ButtonPushedFcn = createCallbackFcn(app, @Load2DimagefromscriptButtonPushed, true);
            app.Load2DimagefromscriptButton.Text = 'Load 2D image from script';
            app.LoadnewmodelButton = uibutton(app.ImageUIFigure, 'push');
            app.LoadnewmodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadnewmodelButtonPushed, true);
            app.LoadnewmodelButton.Text = 'Load new model';
            app.RbmmEditFieldLabel = uilabel(app.ImageUIFigure);
            app.RbmmEditFieldLabel.Text = 'Rb [mm]';
            app.RbmmEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.RbmmEditField.Limits = [0 50];
            app.XommEditFieldLabel = uilabel(app.ImageUIFigure);
            app.XommEditFieldLabel.Text = 'Xo [mm]';
            app.XommEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.XommEditField.Limits = [0 1000];
            app.YommEditFieldLabel = uilabel(app.ImageUIFigure);
            app.YommEditFieldLabel.Text = 'Yo [mm]';
            app.YommEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.YommEditField.Limits = [0 1000];
            app.ParametersofnewmodelLabel = uilabel(app.ImageUIFigure);
            app.ParametersofnewmodelLabel.Text = 'Parameters of new model';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageUIFigure)
        end
    end
end